classdef simulink_modal_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIMULINKmodalanalysisUIFigure  matlab.ui.Figure
        TofigureButton                 matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        SavelinsystoworkspaceButton    matlab.ui.control.Button
        VaryonebyoneCheckBox           matlab.ui.control.CheckBox
        modelnameEditField             matlab.ui.control.EditField
        modelnameEditFieldLabel        matlab.ui.control.Label
        linsysnameEditField            matlab.ui.control.EditField
        linsysnameEditFieldLabel       matlab.ui.control.Label
        LoadlinsysButton               matlab.ui.control.Button
        LoaddatafromMATLABworkspaceLabel  matlab.ui.control.Label
        opCheckBox                     matlab.ui.control.CheckBox
        ioCheckBox                     matlab.ui.control.CheckBox
        Hyperlink3                     matlab.ui.control.Hyperlink
        Hyperlink2                     matlab.ui.control.Hyperlink
        Hyperlink                      matlab.ui.control.Hyperlink
        opEditField                    matlab.ui.control.EditField
        ioEditField                    matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        HTML                           matlab.ui.control.HTML
        FileLabel                      matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        ReplacenamesButton             matlab.ui.control.Button
        SubsystemnamesTextAreaLabel_4  matlab.ui.control.Label
        SubsystemnamesTextAreaLabel_3  matlab.ui.control.Label
        SubsystemnamesTextAreaLabel_2  matlab.ui.control.Label
        newstatenames                  matlab.ui.control.TextArea
        statenamestoreplace            matlab.ui.control.TextArea
        SimulinkblocksCheckBox         matlab.ui.control.CheckBox
        SubsystemnamesTextAreaLabel    matlab.ui.control.Label
        SubsystemnamesTextArea         matlab.ui.control.TextArea
        SinglelinearizationButton      matlab.ui.control.Button
        BatchlinearizationButton       matlab.ui.control.Button
        StepsSpinner                   matlab.ui.control.Spinner
        StepsSpinnerLabel              matlab.ui.control.Label
        Batchtable                     matlab.ui.control.Table
        ParamstochangeSpinner          matlab.ui.control.Spinner
        ParamstochangeSpinnerLabel     matlab.ui.control.Label
        SimulinkfileLabel              matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        EigenvaluesTab                 matlab.ui.container.Tab
        TabGroup3                      matlab.ui.container.TabGroup
        SingleTab                      matlab.ui.container.Tab
        singleDropDown                 matlab.ui.control.DropDown
        Panel_3                        matlab.ui.container.Panel
        singleeigenAxes                matlab.ui.control.UIAxes
        ParamDropDown_singleeigen      matlab.ui.control.DropDown
        ParamDropDown_2Label           matlab.ui.control.Label
        UITab                          matlab.ui.container.Tab
        IterationLabel                 matlab.ui.control.Label
        SpinnerUIeigen                 matlab.ui.control.Spinner
        ParticipationsCheckBox         matlab.ui.control.CheckBox
        UIDropDown                     matlab.ui.control.DropDown
        Panel_2                        matlab.ui.container.Panel
        UIeigenAxes                    matlab.ui.control.UIAxes
        ParamValueLabel                matlab.ui.control.Label
        ParamDropDown_UIeigen          matlab.ui.control.DropDown
        ParamDropDown_2Label_2         matlab.ui.control.Label
        SensitivitiesTab               matlab.ui.container.Tab
        sensitivitiesDropDown          matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        sensitivitiesAxes              matlab.ui.control.UIAxes
        ParamDropDown_sensitivities_2  matlab.ui.control.DropDown
        ParamDropDown_2Label_5         matlab.ui.control.Label
        ParamDropDown_sensitivities_1  matlab.ui.control.DropDown
        ParamDropDown_2Label_4         matlab.ui.control.Label
        ParamDropDown_sensitivities    matlab.ui.control.DropDown
        ParamDropDown_2Label_3         matlab.ui.control.Label
        ParticipationsTab              matlab.ui.container.Tab
        SubsystemDropDown              matlab.ui.control.DropDown
        SubsystemLabel                 matlab.ui.control.Label
        Panel_7                        matlab.ui.container.Panel
        PartiAxes                      matlab.ui.control.UIAxes
        SystemDropDown                 matlab.ui.control.DropDown
        SystemDropDownLabel            matlab.ui.control.Label
        RelevanceTab                   matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        EigenvaluerelevanceTab         matlab.ui.container.Tab
        Panel_4                        matlab.ui.container.Panel
        PoleRelAxes                    matlab.ui.control.UIAxes
        SystemDropDownRel              matlab.ui.control.DropDown
        SystemDropDown_2Label          matlab.ui.control.Label
        eigenDropDown                  matlab.ui.control.DropDown
        StaterelevanceTab              matlab.ui.container.Tab
        XlabelforploteverynumberofstatesSpinner  matlab.ui.control.Spinner
        XlabelforploteverynumberofstatesSpinnerLabel  matlab.ui.control.Label
        TabGroup4                      matlab.ui.container.TabGroup
        StaterelevancetableTab         matlab.ui.container.Tab
        StateTable                     matlab.ui.control.Table
        StaterelevanceplotTab          matlab.ui.container.Tab
        Panel_5                        matlab.ui.container.Panel
        StateRelAxes                   matlab.ui.control.UIAxes
        SystemDropDownRelStates        matlab.ui.control.DropDown
        SystemDropDown_2Label_2        matlab.ui.control.Label
        StaterelevancejumpTab          matlab.ui.container.Tab
        Panel_6                        matlab.ui.container.Panel
        RelJumpAxes                    matlab.ui.control.UIAxes
        StatetofindEditField           matlab.ui.control.EditField
        StatetofindEditFieldLabel      matlab.ui.control.Label
        ParamDropDown                  matlab.ui.control.DropDown
        SystemDropDown_2Label_3        matlab.ui.control.Label
    end

    
    properties (Access = public)
        simulink_name       % file name
        op                  % Operating point
        linsys              % linear system
        modparticipationori % participation of actual system
        subsystem_name      % For participation representation
        ordered_states      % Ordered state names for actual iteration
        actual_axes_name    % Name of the actual axes
        io                  % I/Os of Simulink file
        R_state_batch
        ordered_states_batch
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, simulink_model)
            ParamstochangeSpinnerValueChanged(app) % Complete param table
            app.actual_axes_name = 'app.sensitivitiesAxes'; % Initial axes for figure print
            if exist('simulink_model', 'var')
                app.simulink_name = simulink_model;
                app.SimulinkfileLabel.Text = simulink_model;
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.linsys = []; app.io = []; app.op = []; % Clear variables
            [file,path] = uigetfile({'*.slx','Simulink model files'},'Select SIMULINK model file','microgrid_10dg');
            drawnow;
            figure(app.SIMULINKmodalanalysisUIFigure)
            if path == 0
                return
            end
            cd(path);
            app.simulink_name = erase(file,'.slx');
            app.SimulinkfileLabel.Text = file;
        end

        % Button pushed function: LoadlinsysButton
        function LoadlinsysButtonPushed(app, event)
            try
                app.linsys = evalin('base', app.linsysnameEditField.Value);
            catch ME
                uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check linsys name', 'Icon', 'error')
                return
            end
            app.simulink_name = app.modelnameEditField.Value;
            app.SystemDropDown.Items = {'Linearized system'};
            app.SystemDropDownRel.Items = {'Linearized system'};
            app.SystemDropDownRelStates.Items = {'Linearized system'};
            %ReplacenamesButtonPushed(app, event);
            SubsystemnamesTextAreaValueChanged(app, event);
            app.ParamDropDown.Items = {'Linearized system'};
            app.ParamDropDown_singleeigen.Items = {'Linearized system'};
            app.ParamDropDown_UIeigen.Items = {'Linearized system'};
            app.ParamDropDown_sensitivities.Items = {'Linearized system'};
            app.SpinnerUIeigen.Value = 1;
            app.ParamValueLabel.Visible = "off";
            app.SpinnerUIeigen.Visible = "off";
            app.XlabelforploteverynumberofstatesSpinner.Value = ceil(length(app.linsys.StateName)/20);
        end

        % Button pushed function: SinglelinearizationButton
        function SinglelinearizationButtonPushed(app, event)
            message = uiprogressdlg(app.SIMULINKmodalanalysisUIFigure,'Title','Operation in progress', ...
                'Message','Linearizing Simulink system, please wait','Indeterminate','on');
            if isempty(app.simulink_name)
                uialert(app.SIMULINKmodalanalysisUIFigure, 'Simulink model not defined', ...
                    'Specify Simulink model', 'Icon', 'error')
                    return
            else
                try
                    load_system(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Error loading Simulink model', 'Icon', 'error')
                    return
                end
            end
            if ~app.ioCheckBox.Value
                try
                    app.io = getlinio(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check I/Os', 'Icon', 'error')
                    return
                end
            else
                try
                    app.io = evalin('base', app.ioEditField.Value);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check I/Os', 'Icon', 'error')
                    return
                end
                if ~isa(app.io, 'linearize.IOPoint')
                    uialert(app.SIMULINKmodalanalysisUIFigure, 'I/Os are not specified as linearize.IOPoint', ...
                    'Specify I/Os with linio function', 'Icon', 'error')
                    return
                end
            end
            if ~app.opCheckBox.Value
                try
                    app.op = operspec(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check model', 'Icon', 'error')
                    return
                end
            else
                try
                    app.op = evalin('base', app.opEditField.Value);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check op', 'Icon', 'error')
                    return
                end
                if ~isa(app.op, 'opcond.OperatingSpec') && ~isa(app.op, 'opcond.OperatingPoint')
                    uialert(app.SIMULINKmodalanalysisUIFigure, ['op is not specified as opcond.OperatingSpec' ...
                        ' or opcond.OperatingPoint'], 'Specify op with operspec or findop function', 'Icon', 'error')
                    return
                end
            end
            try
                app.linsys = sminreal(linearize(app.simulink_name,app.io,app.op, linearizeOptions('UseFullBlockNameLabels', 'on')));
            catch ME
                uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check model', 'Icon', 'error')
                return
            end
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure, 'I/Os define an empty linear system', ...
                    'Check I/Os', 'Icon', 'error')
                return
            end
            app.SystemDropDown.Items = {'Linearized system'};
            app.SystemDropDownRel.Items = {'Linearized system'};
            app.SystemDropDownRelStates.Items = {'Linearized system'};
            %ReplacenamesButtonPushed(app, event);
            SubsystemnamesTextAreaValueChanged(app, event);
            app.ParamDropDown.Items = {'Linearized system'};
            app.ParamDropDown_singleeigen.Items = {'Linearized system'};
            app.ParamDropDown_UIeigen.Items = {'Linearized system'};
            app.ParamDropDown_sensitivities.Items = {'Linearized system'};
            app.SpinnerUIeigen.Value = 1;
            app.ParamValueLabel.Visible = "off";
            app.SpinnerUIeigen.Visible = "off";
            try
                app.XlabelforploteverynumberofstatesSpinner.Value = ceil(length(app.linsys.StateName)/20);
            catch
                errordlg('Check that linearized model has states')
            end
            close(message)
        end

        % Button pushed function: BatchlinearizationButton
        function BatchlinearizationButtonPushed(app, event)
            message = uiprogressdlg(app.SIMULINKmodalanalysisUIFigure,'Title','Operation in progress','Message', ...
                'Linearizing Simulink system, please wait',...
        'Indeterminate','on');
            if isempty(app.simulink_name)
                uialert(app.SIMULINKmodalanalysisUIFigure, 'Simulink model not defined', ...
                    'Specify Simulink model', 'Icon', 'error')
                    return
            else
                try
                    load_system(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Error loading Simulink model', 'Icon', 'error')
                    return
                end
            end
            if ~app.ioCheckBox.Value
                try
                    app.io = getlinio(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check I/Os', 'Icon', 'error')
                    return
                end
            else
                try
                    app.io = evalin('base', app.ioEditField.Value);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check I/Os', 'Icon', 'error')
                    return
                end
                if ~isa(app.io, 'linearize.IOPoint')
                    uialert(app.SIMULINKmodalanalysisUIFigure, 'I/Os are not specified as linearize.IOPoint', ...
                    'Specify I/Os with linio function', 'Icon', 'error')
                    return
                end
            end
            if ~app.opCheckBox.Value
                try
                    app.op = operspec(app.simulink_name);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check model', 'Icon', 'error')
                    return
                end
            else
                try
                    app.op = evalin('base', app.opEditField.Value);
                catch ME
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Check op', 'Icon', 'error')
                    return
                end
                if ~(isa(app.op, 'opcond.OperatingSpec') || isa(app.op, 'opcond.OperatingPoint'))
                    uialert(app.SIMULINKmodalanalysisUIFigure, 'op is not specified as opcond.OperatingSpec or opcond.OperatingPoint', ...
                    'Specify op with operspec or findop function', 'Icon', 'error')
                    return
                end
            end
            if app.VaryonebyoneCheckBox.Value
                parameter_values = zeros(size(app.Batchtable.Data,1),app.StepsSpinner.Value);
                for i = 1:size(app.Batchtable.Data,1)
                    if app.Batchtable.Data{i,4}
                        parameter_values(i,:) = logspace(log10(app.Batchtable.Data{i,2}), log10(app.Batchtable.Data{i,3}), app.StepsSpinner.Value);
                    else
                        parameter_values(i,:) = linspace(app.Batchtable.Data{i,2}, app.Batchtable.Data{i,3}, app.StepsSpinner.Value);
                    end
                end
                param = [];
                for i = size(app.Batchtable.Data,1):-1:1 % Backwards to preallocate in first iteration
                    param(i).Name = app.Batchtable.Data{i,1};
                    try
                        param(i).Value = repmat(evalin('base',app.Batchtable.Data{i,1}), 1, size(app.Batchtable.Data,1)*app.StepsSpinner.Value);
                    catch
                        close(message)
                        uialert(app.SIMULINKmodalanalysisUIFigure, [app.Batchtable.Data{i,1} ' not defined in MATLAB base workspace'], ...
                            'Parameter error', 'Icon', 'error')
                        return
                    end
                    param_pos = (i-1)*app.StepsSpinner.Value+1:i*app.StepsSpinner.Value;
                    param(i).Value(param_pos) = parameter_values(i,:);
                end
            else
                param = [];
                for i = 1:size(app.Batchtable.Data,1)
                    if app.Batchtable.Data{i,4}
                        parameter_values(i,:) = logspace(log10(app.Batchtable.Data{i,2}), log10(app.Batchtable.Data{i,3}), app.StepsSpinner.Value);
                    else
                        parameter_values(i,:) = linspace(app.Batchtable.Data{i,2}, app.Batchtable.Data{i,3}, app.StepsSpinner.Value);
                    end
                end
                for i = size(app.Batchtable.Data,1):-1:1 % Backwards to preallocate in first iteration
                    param(i).Name = app.Batchtable.Data{i,1};
                    if app.Batchtable.Data{i,4}
                        param(i).Value = logspace(log10(app.Batchtable.Data{i,2}), log10(app.Batchtable.Data{i,3}), app.StepsSpinner.Value);
                    else
                        param(i).Value = linspace(app.Batchtable.Data{i,2}, app.Batchtable.Data{i,3}, app.StepsSpinner.Value);
                    end
                end
            end
            try
                app.linsys = sminreal(linearize(app.simulink_name,app.io,app.op,param, linearizeOptions('UseFullBlockNameLabels', 'on')));
            catch ME
                if strcmp(ME.identifier, 'Slcontrol:sllinearizer:ParamVaryError')
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                    'Parameter not tunable', 'Icon', 'warning')
                    app.linsys = sminreal(linearize(app.simulink_name,app.io,app.op,param,linearizeOptions('AreParamsTunable',false,'UseFullBlockNameLabels', 'on')));
                else
                    uialert(app.SIMULINKmodalanalysisUIFigure, ME.message, ...
                        'Check model', 'Icon', 'error')
                    return
                end
            end
            system_names = strings(size(app.Batchtable.Data,1)*app.StepsSpinner.Value,1);
            for i = 1:size(app.Batchtable.Data,1)
                system_names((i-1)*app.StepsSpinner.Value+1:i*app.StepsSpinner.Value,:) = strcat(param(i).Name, ...
                    ' =  ', string(parameter_values(i,:)));
            end
            if app.VaryonebyoneCheckBox.Value
                app.SystemDropDown.Items = string(system_names);
                app.SystemDropDownRel.Items = string(system_names);
                app.SystemDropDownRelStates.Items = string(system_names);
                %ReplacenamesButtonPushed(app, event);
                SubsystemnamesTextAreaValueChanged(app, event);
                app.ParamDropDown.Items = app.Batchtable.Data(:,1);
                app.ParamDropDown_singleeigen.Items = app.Batchtable.Data(:,1);
                app.ParamDropDown_UIeigen.Items = app.Batchtable.Data(:,1);
                app.ParamDropDown_sensitivities.Items = app.Batchtable.Data(:,1);
            else
                app.SystemDropDown.Items = string(system_names(1:app.StepsSpinner.Value));
                app.SystemDropDownRel.Items = string(system_names(1:app.StepsSpinner.Value));
                app.SystemDropDownRelStates.Items = string(system_names(1:app.StepsSpinner.Value));
                %ReplacenamesButtonPushed(app, event);
                SubsystemnamesTextAreaValueChanged(app, event);
                app.ParamDropDown.Items = app.Batchtable.Data(1,1);
                app.ParamDropDown_singleeigen.Items = app.Batchtable.Data(1,1);
                app.ParamDropDown_UIeigen.Items = app.Batchtable.Data(1,1);
                app.ParamDropDown_sensitivities.Items = app.Batchtable.Data(1,1);
            end
            app.SpinnerUIeigen.Limits(2) = app.StepsSpinner.Value;
            app.ParamValueLabel.Visible = "on";
            app.SpinnerUIeigen.Visible = "on";
            app.XlabelforploteverynumberofstatesSpinner.Value = ceil(length(app.linsys.StateName)/20);
            app.CalculateStatereljumpButtonPushed(event)
            close(message)
        end

        % Value changed function: ParamstochangeSpinner
        function ParamstochangeSpinnerValueChanged(app, event)
            value = app.ParamstochangeSpinner.Value;
            len = size(app.Batchtable.Data,1);
            if len == 0
                app.Batchtable.Data = {'Fi1', 0.8, 0.9, false};
                len = 1;
            end
            if len < value
                for i=len+1:value
                    app.Batchtable.Data(i,:) = {strcat('Fi',num2str(i)), 0.75, 1, false};
                end
            elseif len > value
                for i=value+1:len
                    app.Batchtable.Data(i,:) = [];
                end
            end
        end

        % Callback function: Batchtable, Batchtable, StepsSpinner
        function BatchtableCellEdit(app, event)
            if isprop(event, 'Indices')
                indices = event.Indices;
                switch indices(2)
                    case {2,3}
                        if ~isnumeric(app.Batchtable.Data{indices(1),indices(2)})
                            app.Batchtable.Data{indices(1),indices(2)} = 0;
                        end
                    case 4
                        if ~islogical(app.Batchtable.Data{indices(1),indices(2)})
                            app.Batchtable.Data{indices(1),indices(2)} = '0';
                        end
                end
            end
            app.linsys = [];
        end

        % Callback function: ParticipationsTab, SystemDropDown
        function ParticipationsTabButtonDown(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            A = app.linsys(:,:,find(string(app.SystemDropDown.Items) == app.SystemDropDown.Value)).a;
            [V1,D1] = eig(A); % right eigenvectors and eigenvalues
            [~,ind] = sort(diag(D1),'ComparisonMethod','real'); % To sort eigenvalues and eigenvectors
            W1 = inv(V1); % Definition used in IIT
            V1 = V1(:,ind);
            W1 = W1(ind,:);

            Participation = zeros(length(V1)); % Preallocate
            modparticipation = Participation;
            app.modparticipationori = Participation;
            for i = 1:length(V1)
                for n = 1:length(V1)
                    Participation(n,i)=W1(i,n)*V1(n,i);
                    modparticipation(n,i) = abs(Participation(n,i)); % Normally only participation Magnitude is considered
                end
                app.modparticipationori(:,i)=modparticipation(:,i)/sum(modparticipation(:,i)); % Normalization
            end
            set(app.PartiAxes,'ColorScale', 'lin')
            set(app.PartiAxes, 'YGrid', 'on', 'XGrid', 'on')
            set(app.PartiAxes,'layer','top')
            view(app.PartiAxes, 0,270)
            colorbar(app.PartiAxes, 'northoutside')
            zlabel(app.PartiAxes, '|Participation|')
            colormap_used = jet(100);
            colormap_used(1,:) = [1 1 1]; % All values smaller than threesold are white
            colormap(app.PartiAxes, colormap_used);
            xticks(app.PartiAxes, 1.5:length(modparticipation)+0.5)
            SubsystemDropDownValueChanged(app, event);
            app.actual_axes_name = 'app.PartiAxes';
        end

        % Value changed function: SimulinkblocksCheckBox, 
        % ...and 1 other component
        function SubsystemnamesTextAreaValueChanged(app, event)
            value = app.SubsystemnamesTextArea.Value;
            linsysnames = app.linsys.StateName;
            if app.SimulinkblocksCheckBox.Value
                app.subsystem_name = string(extractBefore(linsysnames,"/"));
            end
            for i = 1:length(value)
                if ~isempty(value{i})
                    app.subsystem_name(contains(app.subsystem_name, value{i})) = value{i};
                end
            end
            unique_subsystem_name = unique(app.subsystem_name);
            app.SubsystemDropDown.Items = [unique_subsystem_name; " All"];
        end

        % Button pushed function: ReplacenamesButton
        function ReplacenamesButtonPushed(app, event)
            namesdiff = length(app.statenamestoreplace.Value) - length(app.newstatenames.Value);
            if namesdiff > 0
                app.newstatenames.Value(end+1:end+namesdiff) = {''};
            else
                app.statenamestoreplace.Value(end+1:end+namesdiff) = {''};
            end
            app.linsys.StateName = replace(app.linsys.StateName, [app.simulink_name '/'], '');
            for i=1:length(app.statenamestoreplace.Value)
                old = app.statenamestoreplace.Value{i};
                if ~isempty(old)
                    app.linsys.StateName = regexprep(app.linsys.StateName, old, app.newstatenames.Value{i});
                end
            end
            SubsystemnamesTextAreaValueChanged(app, event);
        end

        % Callback function: EigenvaluerelevanceTab, SystemDropDownRel, 
        % ...and 1 other component
        function eigenDropDownValueChanged(app, event)
            value = app.eigenDropDown.Value;
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            % Calculate participation factors in the BALANCED system
            if isempty(app.linsys.b) || isempty(app.linsys.c)
                uialert(app.SIMULINKmodalanalysisUIFigure,'The "balreal" command cannot be used for models without inputs or outputs','Invalid IOs')
                return
            end
            [balsys, g, ~, ~] = balreal(app.linsys(:,:,find(string(app.SystemDropDownRel.Items) ...
                == app.SystemDropDownRel.Value)));
            [V1_bal,D1] = eig(balsys.a);
            [eigen,ind] = sort(diag(D1),'ComparisonMethod','real');
            W1 = inv(V1_bal);
            V1_bal = V1_bal(:,ind);
            W1 = W1(ind,:);
            Participation = zeros(length(V1_bal));
            modparticipation = Participation;
            parti_bal = Participation;

            for i = 1:length(V1_bal)
                for n = 1:length(V1_bal)
                    Participation(n,i)=W1(i,n)*V1_bal(n,i);
                    modparticipation(n,i) = abs(Participation(n,i));
                end
                parti_bal(:,i)=modparticipation(:,i)/sum(modparticipation(:,i));
            end

            R = g'*parti_bal;
            R_eig = R/max(R);
            switch value
                case 'Mag & phase'
                    cla(app.PoleRelAxes,'reset')
                    set(app.PoleRelAxes,'Units', 'Normalized','Position',[0.1 0.1 0.8 0.8])
                    p1 = scatter(app.PoleRelAxes,abs(eigen),rad2deg(wrapTo2Pi(angle(eigen))),60,R_eig,'x','LineWidth',2);
                    title(app.PoleRelAxes,'Relevance of each eigenvalue')
                    xlabel(app.PoleRelAxes,'Magnitude (rad/s)')
                    ylabel(app.PoleRelAxes,'Phase (degrees)')
                    set(app.PoleRelAxes, 'XScale', 'log')
                    set(app.PoleRelAxes,'ColorScale','log')
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Phase (degrees)';
                    p1.DataTipTemplate.DataTipRows(3).Label = 'Relevance';
                    colormap(app.PoleRelAxes,jet)
                    c = colorbar(app.PoleRelAxes);
                    c.Label.String = 'R(\lambda_i)';
                    hold(app.PoleRelAxes,'on')
                    xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                    for i=logspace(2*xlimit(1)-xlimit(2),xlimit(2),40)
                        line(app.PoleRelAxes,min(max(i./abs(cos(deg2rad(90:1:270))),0.99*10^xlimit(1)), ...
                            1.01*10^xlimit(2)),90:1:270,'LineStyle',':','Color','black','HandleVisibility','off');
                    end
                    yline(app.PoleRelAxes,270); yline(app.PoleRelAxes,90); 
                    axis(app.PoleRelAxes,[10^xlimit(1) 10^xlimit(2) -Inf 180])
                    hold(app.PoleRelAxes,'off')
                    
               case 'Mag & damp'
                    cla(app.PoleRelAxes,'reset')
                    set(app.PoleRelAxes,'Units', 'Normalized','Position',[0.1 0.1 0.8 0.8])
                    p1 = scatter(app.PoleRelAxes,abs(eigen),sin(angle(eigen)-pi/2),60,R_eig,'x','LineWidth',2);
                    title(app.PoleRelAxes,'Relevance of each eigenvalue')
                    xlabel(app.PoleRelAxes,'Magnitude (rad/s)')
                    ylabel(app.PoleRelAxes,'Damping')
                    set(app.PoleRelAxes, 'XScale', 'log')
                    set(app.PoleRelAxes,'ColorScale','log')
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Damping';
                    p1.DataTipTemplate.DataTipRows(3).Label = 'Relevance';
                    colormap(app.PoleRelAxes,jet)
                    c = colorbar(app.PoleRelAxes);
                    c.Label.String = 'R(\lambda_i)';
                    hold(app.PoleRelAxes,'on')
                    xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                    yline(app.PoleRelAxes,0); 
                    axis(app.PoleRelAxes,[10^xlimit(1) 10^xlimit(2) -Inf 1])
                    hold(app.PoleRelAxes,'off')     

                case 'Real & imag'
                    cla(app.PoleRelAxes,'reset')
                    set(app.PoleRelAxes,'Units', 'Normalized','Position',[0.1 0.1 0.8 0.8])
                    p1 = scatter(app.PoleRelAxes,real(eigen),imag(eigen),60,R_eig,'x','LineWidth',2);
                    title(app.PoleRelAxes,'Relevance of each eigenvalue')
                    xlabel(app.PoleRelAxes,'Re\{\lambda\}')
                    ylabel(app.PoleRelAxes,'Im\{\lambda\}')
                    set(app.PoleRelAxes, 'XScale', 'lin')
                    set(app.PoleRelAxes,'ColorScale','log')
                    p1.DataTipTemplate.DataTipRows(1).Label = 'Re\{\lambda\}';
                    p1.DataTipTemplate.DataTipRows(2).Label = 'Im\{\lambda\}';
                    p1.DataTipTemplate.DataTipRows(3).Label = 'Relevance';
                    colormap(app.PoleRelAxes,jet)
                    c = colorbar(app.PoleRelAxes);
                    c.Label.String = 'R(\lambda_i)';

                case 'Stem'
                    cla(app.PoleRelAxes,'reset')
                    set(app.PoleRelAxes,'Units', 'Normalized','Position',[0.1 0.1 0.8 0.8])
                    hold(app.PoleRelAxes,"on")
                    title(app.PoleRelAxes,'Relevance of each eigenvalue')
                    yyaxis(app.PoleRelAxes,'left')
                    ylabel(app.PoleRelAxes,'R(\lambda)')
                    set(app.PoleRelAxes, 'YScale', 'log')
                    stem(app.PoleRelAxes, R_eig)
                    yyaxis(app.PoleRelAxes,'right')
                    set(app.PoleRelAxes, 'YScale', 'log')
                    ylabel(app.PoleRelAxes, '-1/Re\{\lambda_i\}')
                    timeconst = -1./real(eigen);
                    plot(app.PoleRelAxes, 1:length(timeconst), timeconst,'ro')
                    axis(app.PoleRelAxes, [1 length(timeconst) -inf inf])
                    legend(app.PoleRelAxes,'R(\lambda)','-1/Re\{\lambda_i\}','Location','northwest')
            end
            app.actual_axes_name = 'app.PoleRelAxes';
        end

        % Callback function: StaterelevanceTab, SystemDropDownRelStates
        function SystemDropDownRelStatesValueChanged(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            if isempty(app.linsys.b) || isempty(app.linsys.c)
                uialert(app.SIMULINKmodalanalysisUIFigure,'The "balreal" command cannot be used for models without inputs or outputs','Invalid IOs')
                return
            end
            A = app.linsys(:,:,find(string(app.SystemDropDownRelStates.Items) == app.SystemDropDownRelStates.Value)).a;
            [V1,D1] = eig(A); % right eigenvectors and eigenvalues
            [~,ind] = sort(diag(D1),'ComparisonMethod','real'); % To sort eigenvalues and eigenvectors
            W1 = inv(V1); % Definition used in IIT
            V1 = V1(:,ind);
            W1 = W1(ind,:);
            Participation = zeros(length(V1)); % Preallocate
            modparticipation = Participation;
            modpartiori = Participation;
            for i = 1:length(V1)
                for n = 1:length(V1)
                    Participation(n,i)=W1(i,n)*V1(n,i);
                    modparticipation(n,i) = abs(Participation(n,i)); % Normally only participation Magnitude is considered
                end
                modpartiori(:,i)=modparticipation(:,i)/sum(modparticipation(:,i)); % Normalization
            end
            % Calculate participation factors in the BALANCED system

            [balsys, g, ~, ~] = balreal(app.linsys(:,:,find(string(app.SystemDropDownRelStates.Items) == app.SystemDropDownRelStates.Value)));
            if any(g == Inf)
                uialert(app.SIMULINKmodalanalysisUIFigure,'State relevance approximated for unstable systems','Caution')
            end
            g(g==Inf) = 1e6;
            [V1_bal,D1] = eig(balsys.a);
            [~,ind] = sort(diag(D1),'ComparisonMethod','real');
            W1 = inv(V1_bal);
            V1_bal = V1_bal(:,ind);
            W1 = W1(ind,:);
            Participation = zeros(length(V1_bal));
            modparticipation = Participation;
            parti_bal = Participation;

            for i = 1:length(V1_bal)
                for n = 1:length(V1_bal)
                    Participation(n,i)=W1(i,n)*V1_bal(n,i);
                    modparticipation(n,i) = abs(Participation(n,i));
                end
                parti_bal(:,i)=modparticipation(:,i)/sum(modparticipation(:,i));
            end
            R = g'*parti_bal;
            R_eig = R/max(R);
            R_state = modpartiori*R_eig';
            R_state = R_state/sum(R_state); % Normalize state relevance
            x_lim = xlim(app.StateRelAxes);
            [R_state_sorted,ind_states] = sort(R_state,'descend');
            app.ordered_states = app.linsys.StateName(ind_states);
            ordered_states_table = table((1:length(A))',app.ordered_states,num2cell(R_state_sorted));
            set(app.StateTable,'Data',ordered_states_table)
            set(app.StateRelAxes, 'yscale','log');
            title(app.StateRelAxes, 'State relevance');
            ylabel(app.StateRelAxes, 'State relevance');
            xlabel(app.StateRelAxes, 'State');
            hold(app.StateRelAxes,'off');
            bar(app.StateRelAxes, 1:length(R_state_sorted), R_state_sorted);
            xticks(app.StateRelAxes, 1:length(R_state_sorted));
            xticklabels(app.StateRelAxes, app.ordered_states);
            if x_lim(1) ~= 0 && x_lim(2) ~= 0
                axis(app.StateRelAxes,[x_lim -inf inf])
            end
            RefreshxaxislabelsforplotButtonPushed(app, event);
            app.actual_axes_name = 'app.StateRelAxes';
        end

        % Value changed function: XlabelforploteverynumberofstatesSpinner
        function RefreshxaxislabelsforplotButtonPushed(app, event)
            step_x = app.XlabelforploteverynumberofstatesSpinner.Value;
            try
                x_ticks = 1:step_x:length(app.ordered_states);
                xticks(app.StateRelAxes, x_ticks);
                xticklabels(app.StateRelAxes, app.ordered_states(x_ticks));
            catch
                return
            end
        end

        % Button down function: RelevanceTab
        function RelevanceTabButtonDown(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            switch find(event.Source.Children.Children == event.Source.Children.SelectedTab)
                case 1
                    eigenDropDownValueChanged(app, event)
                case 2
                    SystemDropDownRelStatesValueChanged(app, event)
                case 3
                    RelJumpAxesButtonDown(app, event)
            end
        end

        % Button down function: StaterelevancejumpTab
        function RelJumpAxesButtonDown(app, event)
            
        end

        % Button down function: EigenvaluesTab
        function EigenvaluesTabButtonDown(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            switch find(event.Source.Children.Children == event.Source.Children.SelectedTab)
                case 1
                    SingleTabButtonDown(app, event)
                case 2
                    UITabButtonDown(app, event)
                case 3
                    SensitivitiesTabButtonDown(app, event)
            end     
        end

        % Callback function: ParamDropDown_singleeigen, SingleTab, 
        % ...and 1 other component
        function SingleTabButtonDown(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            if isprop(event.Source.Parent, 'SelectedTab')
                event.Source.Parent.SelectedTab = event.Source;
            end
            wb = uiprogressdlg(app.SIMULINKmodalanalysisUIFigure, "Indeterminate","on","Message","Plotting eigenvalues");
            param = find(app.ParamDropDown_singleeigen.Value == string(app.ParamDropDown_singleeigen.Items));
            if app.Batchtable.Data{param,4}
                parameter_values = logspace(log10(app.Batchtable.Data{param,2}), log10(app.Batchtable.Data{param,3}), app.StepsSpinner.Value);
            else
                parameter_values = linspace(app.Batchtable.Data{param,2}, app.Batchtable.Data{param,3}, app.StepsSpinner.Value);
            end
            cla(app.singleeigenAxes, "reset")
            title(app.singleeigenAxes, 'Pole loci')
            hold(app.singleeigenAxes, "on")
            colormap(app.singleeigenAxes,jet)
            if size(app.linsys,3) > 1 % Batch linearization
                c = colorbar(app.singleeigenAxes);
                c.Label.String = app.Batchtable.Data{param,1};
                eigen = zeros(length(app.linsys(:,:,1).a), app.StepsSpinner.Value);
                for sys = (param-1)*app.StepsSpinner.Value+1:param*app.StepsSpinner.Value
                    wb = uiprogressdlg(app.SIMULINKmodalanalysisUIFigure, "Value",(sys-(param-1)*app.StepsSpinner.Value)/app.StepsSpinner.Value,...
                        "ShowPercentage","on","Message","Plotting pole loci");
                    eigen(:,sys-(param-1)*app.StepsSpinner.Value) = eig(app.linsys(:,:,sys).a); % eigenvalues of the system involved in this iteration
                end
                switch app.singleDropDown.Value
                    case 'Mag & phase'
                        xlabel(app.singleeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.singleeigenAxes, 'Phase (degrees)')
                        set(app.singleeigenAxes, 'xscale','log')
                        yline(app.singleeigenAxes, 90)
                        hold(app.singleeigenAxes,'on')
                        for i=1:length(app.linsys(:,:,1).a)
                            p1 = scatter(app.singleeigenAxes, abs(eigen(i,:)), rad2deg(wrapTo2Pi(angle(eigen(i,:)))), 60, parameter_values, 'x', 'LineWidth', 2);
                            p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                            p1.DataTipTemplate.DataTipRows(2).Label = 'Phase (degrees)';
                            p1.DataTipTemplate.DataTipRows(3).Label = app.Batchtable.Data{param,1};
                            row = dataTipTextRow('Damping ratio',round(abs(sin(angle(eigen(i,:))-pi/2)),3));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                        end
                        xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                        for i=logspace(2*xlimit(1)-xlimit(2),xlimit(2),40)
                            line(app.PoleRelAxes,min(max(i./abs(cos(deg2rad(90:1:270))),0.99*10^xlimit(1)), ...
                                1.01*10^xlimit(2)),90:1:270,'LineStyle',':','Color','black','HandleVisibility','off');
                        end
                        axis(app.singleeigenAxes, [-Inf Inf -Inf 180])
                    case 'Mag & damp'
                        xlabel(app.singleeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.singleeigenAxes, 'Damping')
                        set(app.singleeigenAxes, 'xscale','log')
                        yline(app.singleeigenAxes, 0)
                        for i=1:length(app.linsys(:,:,1).a)
                            p1 = scatter(app.singleeigenAxes, abs(eigen(i,:)), sin(angle(eigen(i,:))-pi/2), 60, parameter_values, 'x', 'LineWidth', 2);
                            p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                            p1.DataTipTemplate.DataTipRows(2).Label = 'Damping';
                            p1.DataTipTemplate.DataTipRows(3).Label = app.Batchtable.Data{param,1};
                        end
                        xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                        axis(app.singleeigenAxes, [-Inf Inf -Inf 1])
                    case 'Real & imag'
                        xlabel(app.singleeigenAxes, 'Real part (rad/s)')
                        ylabel(app.singleeigenAxes, 'Imaginary part (rad/s)')
                        set(app.singleeigenAxes, 'xscale','lin')
                        for i=1:length(app.linsys(:,:,1).a)
                            p1 = scatter(app.singleeigenAxes, real(eigen(i,:)), imag(eigen(i,:)), 60, parameter_values, 'x', 'LineWidth', 2);
                            p1.DataTipTemplate.DataTipRows(1).Label = 'Real';
                            p1.DataTipTemplate.DataTipRows(2).Label = 'Imaginary';
                            p1.DataTipTemplate.DataTipRows(3).Label = app.Batchtable.Data{param,1};
                            row = dataTipTextRow('Damping ratio',round(abs(sin(angle(eigen(i,:))-pi/2)),3));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                        end
                end
            else % Single linearization
                eigen = eig(app.linsys.a);
                switch app.singleDropDown.Value
                    case 'Mag & phase'
                        xlabel(app.singleeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.singleeigenAxes, 'Phase (degrees)')
                        set(app.singleeigenAxes, 'xscale','log')
                        yline(app.singleeigenAxes, 90)
                        hold(app.singleeigenAxes,'on')
                        p1 = plot(app.singleeigenAxes, abs(eigen), rad2deg(wrapTo2Pi(angle(eigen))),'x', 'LineWidth', 2);
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Phase (degrees)';
                        row = dataTipTextRow('Damping ratio',round(abs(sin(angle(eigen)-pi/2)),3));
                        p1.DataTipTemplate.DataTipRows(end+1) = row;
                        xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                        for i=logspace(2*xlimit(1)-xlimit(2),xlimit(2),40)
                            line(app.PoleRelAxes,min(max(i./abs(cos(deg2rad(90:1:270))),0.99*10^xlimit(1)), ...
                                1.01*10^xlimit(2)),90:1:270,'LineStyle',':','Color','black','HandleVisibility','off');
                        end
                        axis(app.singleeigenAxes, [-Inf Inf -Inf 180])
                    case 'Mag & damp'
                        xlabel(app.singleeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.singleeigenAxes, 'Damping')
                        set(app.singleeigenAxes, 'xscale','log')
                        yline(app.singleeigenAxes, 0)
                        p1 = plot(app.singleeigenAxes, abs(eigen), sin(angle(eigen)-pi/2),'x', 'LineWidth', 2);
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude (rad/s)';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Damping';
                        xlimit = log10(xlim(app.PoleRelAxes)); %ylimit = ylim;
                        axis(app.singleeigenAxes, [-Inf Inf -Inf 1])
                    case 'Real & imag'
                        xlabel(app.singleeigenAxes, 'Real part (rad/s)')
                        ylabel(app.singleeigenAxes, 'Imaginary part (rad/s)')
                        set(app.singleeigenAxes, 'xscale','lin')
                        p1 = plot(app.singleeigenAxes, real(eigen), imag(eigen), 'x', 'LineWidth', 2);
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Real';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Imaginary';
                        row = dataTipTextRow('Damping ratio',round(abs(sin(angle(eigen)-pi/2)),3));
                        p1.DataTipTemplate.DataTipRows(end+1) = row;
                end
            end

            close(wb)
            app.actual_axes_name = 'app.singleeigenAxes';
        end

        % Callback function: ParamDropDown_UIeigen, ParticipationsCheckBox,
        % 
        % ...and 3 other components
        function UITabButtonDown(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            if size(app.linsys,3) == 1 % Single linearization
                app.ParamValueLabel.Visible = "off";
                app.IterationLabel.Visible = "off";
                app.SpinnerUIeigen.Visible = "off";
            else
                app.ParamValueLabel.Visible = "on";
                app.IterationLabel.Visible = "on";
                app.SpinnerUIeigen.Visible = "on";
            end
            set(app.SIMULINKmodalanalysisUIFigure, 'pointer', 'watch')
            drawnow;
            if app.ParticipationsCheckBox.Value == 0
                if isprop(event.Source.Parent, 'SelectedTab')
                    event.Source.Parent.SelectedTab = event.Source;
                end
                param = find(app.ParamDropDown_UIeigen.Value == string(app.ParamDropDown_UIeigen.Items));
                if app.SpinnerUIeigen.Limits(2) == inf
                    sys = 1;
                else
                    sys = app.SpinnerUIeigen.Value + app.SpinnerUIeigen.Limits(2)*(param-1);
                end
                if app.Batchtable.Data{param,4}
                    parameter_values = logspace(log10(app.Batchtable.Data{param,2}), log10(app.Batchtable.Data{param,3}), app.StepsSpinner.Value);
                else
                    parameter_values = linspace(app.Batchtable.Data{param,2}, app.Batchtable.Data{param,3}, app.StepsSpinner.Value);
                end
                app.ParamValueLabel.Text = ['Value = ' num2str(parameter_values(app.SpinnerUIeigen.Value))];
                cla(app.UIeigenAxes, "reset")
                title(app.UIeigenAxes, 'Pole loci')
                hold(app.UIeigenAxes, "on")
                eigen = eig(app.linsys(:,:,sys).a);
                switch app.UIDropDown.Value
                    case 'Mag & phase'
                        xlabel(app.UIeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.UIeigenAxes, 'Phase (degrees)')
                        yline(app.UIeigenAxes, 90)
                        plot(app.UIeigenAxes, abs(eigen), rad2deg(wrapTo2Pi(angle(eigen))), 'x', 'LineWidth', 2)
                        set(app.UIeigenAxes, 'xscale','log')
                        axis(app.UIeigenAxes, [-inf inf -inf 180])
                    case 'Mag & damp'
                        xlabel(app.UIeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.UIeigenAxes, 'Damping')
                        yline(app.UIeigenAxes, 0)
                        plot(app.UIeigenAxes, abs(eigen), sin(angle(eigen)-pi/2), 'x', 'LineWidth', 2)
                        set(app.UIeigenAxes, 'xscale','log')
                        axis(app.UIeigenAxes, [-inf inf -inf 1])
                    case 'Real & imag'
                        xlabel(app.UIeigenAxes, 'Real part (rad/s)')
                        ylabel(app.UIeigenAxes, 'Imaginary part (rad/s)')
                        plot(app.UIeigenAxes, real(eigen), imag(eigen), 'x', 'LineWidth', 2)
                        set(app.UIeigenAxes, 'xscale','lin')
                end
                set(app.SIMULINKmodalanalysisUIFigure, 'pointer', 'arrow')
                drawnow;
            else
                if isprop(event.Source.Parent, 'SelectedTab')
                    event.Source.Parent.SelectedTab = event.Source;
                end
                param = find(app.ParamDropDown_UIeigen.Value == string(app.ParamDropDown_UIeigen.Items));
                if app.SpinnerUIeigen.Limits(2) == inf
                    sys = 1;
                else
                    sys = app.SpinnerUIeigen.Value + app.SpinnerUIeigen.Limits(2)*(param-1);
                end
                if app.Batchtable.Data{param,4}
                    parameter_values = logspace(log10(app.Batchtable.Data{param,2}), log10(app.Batchtable.Data{param,3}), app.StepsSpinner.Value);
                else
                    parameter_values = linspace(app.Batchtable.Data{param,2}, app.Batchtable.Data{param,3}, app.StepsSpinner.Value);
                end
                app.ParamValueLabel.Text = ['Value = ' num2str(parameter_values(app.SpinnerUIeigen.Value))];
                cla(app.UIeigenAxes, "reset")
                title(app.UIeigenAxes, 'Pole loci')
                hold(app.UIeigenAxes, "on")
                [V1,D1] = eig(app.linsys(:,:,sys).a); % right eigenvectors and eigenvalues
                [eigen,ind] = sort(diag(D1),'ComparisonMethod','real'); % To sort eigenvalues and eigenvectors
                W1 = inv(V1); % Definition used in IIT
                V1 = V1(:,ind);
                W1 = W1(ind,:);
                Participation = zeros(length(V1)); % Preallocate
                modparticipation = Participation;
                app.modparticipationori = Participation;
                for i = 1:length(V1)
                    for n = 1:length(V1)
                        Participation(n,i)=W1(i,n)*V1(n,i);
                        modparticipation(n,i) = abs(Participation(n,i)); % Normally only participation Magnitude is considered
                    end
                    modparticipation(:,i)=modparticipation(:,i)/sum(modparticipation(:,i)); % Normalization
                end
                [modparticipation,ind] = sort(modparticipation,1,'descend');
                switch app.UIDropDown.Value
                    case 'Mag & phase'
                        xlabel(app.UIeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.UIeigenAxes, 'Phase (degrees)')
                        yline(app.UIeigenAxes, 90)
                        p1 = plot(app.UIeigenAxes, abs(eigen), rad2deg(wrapTo2Pi(angle(eigen))), 'x', 'LineWidth', 2);
                        set(app.UIeigenAxes, 'xscale','log')
                        axis(app.UIeigenAxes, [-inf inf -inf 180])
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Phase';
                        for i = 1:5
                            row = dataTipTextRow(['State ' num2str(i)], app.linsys.StateName(ind(i,:)));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                            row = dataTipTextRow('|Parti| ', cellstr(string(modparticipation(i,:))));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                        end
                    case 'Mag & damp'
                        xlabel(app.UIeigenAxes, 'Magnitude (rad/s)')
                        ylabel(app.UIeigenAxes, 'Damping')
                        yline(app.UIeigenAxes, 0)
                        p1 = plot(app.UIeigenAxes, abs(eigen), sin(angle(eigen)-pi/2), 'x', 'LineWidth', 2);
                        set(app.UIeigenAxes, 'xscale','log')
                        axis(app.UIeigenAxes, [-inf inf -inf 1])
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Magnitude';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Damping';
                        for i = 1:5
                            row = dataTipTextRow(['State ' num2str(i)], app.linsys.StateName(ind(i,:)));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                            row = dataTipTextRow('|Parti| ', cellstr(string(modparticipation(i,:))));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                        end
                    case 'Real & imag'
                        xlabel(app.UIeigenAxes, 'Real part (rad/s)')
                        ylabel(app.UIeigenAxes, 'Imaginary part (rad/s)')
                        p1 = plot(app.UIeigenAxes, real(eigen), imag(eigen), 'x', 'LineWidth', 2);
                        set(app.UIeigenAxes, 'xscale','lin')
                        p1.DataTipTemplate.DataTipRows(1).Label = 'Real';
                        p1.DataTipTemplate.DataTipRows(2).Label = 'Imaginary';
                        for i = 1:5
                            row = dataTipTextRow(['State ' num2str(i)], app.linsys.StateName(ind(i,:)));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                            row = dataTipTextRow('|Parti| ', cellstr(string(modparticipation(i,:))));
                            p1.DataTipTemplate.DataTipRows(end+1) = row;
                        end
                end
                set(app.SIMULINKmodalanalysisUIFigure, 'pointer', 'arrow')
                drawnow;
            end
            app.actual_axes_name = 'app.UIeigenAxes';
        end

        % Callback function: ParamDropDown_sensitivities, SensitivitiesTab,
        % 
        % ...and 1 other component
        function SensitivitiesTabButtonDown(app, event)
            param = find(app.ParamDropDown_sensitivities.Value == string(app.ParamDropDown_sensitivities.Items));
            if app.Batchtable.Data{param,4}
                parameter_values = logspace(log10(app.Batchtable.Data{param,2}), log10(app.Batchtable.Data{param,3}), app.StepsSpinner.Value);
            else
                parameter_values = linspace(app.Batchtable.Data{param,2}, app.Batchtable.Data{param,3}, app.StepsSpinner.Value);
            end
            app.ParamDropDown_sensitivities_1.Items = string(parameter_values);
            ParamDropDown_sensitivities_1ValueChanged(app, event);
        end

        % Value changed function: ParamDropDown_sensitivities_1, 
        % ...and 1 other component
        function ParamDropDown_sensitivities_1ValueChanged(app, event)
            if size(app.linsys,3) == 1 % Single linearization
                app.SingleTab.Parent.SelectedTab = app.SingleTab;
                uialert(app.SIMULINKmodalanalysisUIFigure,'Sensitivity analysis is not valid for single linearization','Calculate batch linearization')
                return
            end
            cla(app.sensitivitiesAxes, "reset")
            param = find(app.ParamDropDown_sensitivities.Value == string(app.ParamDropDown_sensitivities.Items));
            value_1 = app.ParamDropDown_sensitivities_1.Value;
            app.ParamDropDown_sensitivities_2.Items = app.ParamDropDown_sensitivities_1.Items(string(app.ParamDropDown_sensitivities_1.Items) ~= value_1);
            sys1 = find(string(app.ParamDropDown_sensitivities_1.Items) == value_1)*param;
            sys2 = find(string(app.ParamDropDown_sensitivities_1.Items) == app.ParamDropDown_sensitivities_2.Value)*param;
            [V1,D1] = eig(app.linsys(:,:,sys1).a);
            eig2 = eig(app.linsys(:,:,sys2).a);
            eig1 = diag(D1);
            W1 = inv(V1);
            sensitivityeigs = zeros(length(V1),1);
            for i = 1:length(V1)
                sensitivityeigs(i) = W1(i,:)*(app.linsys(:,:,sys2).a-app.linsys(:,:,sys1).a)*V1(:,i);
            end
            start = zeros(2,length(eig1));
            difference = zeros(2,length(eig1));
            finish = zeros(2,length(eig1));
            hold(app.sensitivitiesAxes, "on")
            switch app.sensitivitiesDropDown.Value
                case 'Mag & phase'
                    set(app.sensitivitiesAxes, 'xscale', 'log')
                    for i = 1:length(V1)
                        %if (abs(real(sensitivityeigs(i)))>0.1*abs(real(eig1(i)))) || (abs(sensitivityeigs(i)) > 0.01) ||  real(eig1(i)) > 0
                            start(:,i) = [abs(eig1(i)); wrapTo2Pi(angle(eig1(i)))*180/pi];
                            finish(:,i) = [abs(eig1(i)+sensitivityeigs(i)); wrapTo2Pi(angle(eig1(i)+sensitivityeigs(i)))*180/pi];
                            plot(app.sensitivitiesAxes, abs(eig1(i)),wrapTo2Pi(angle(eig1(i)))*180/pi,'o','color',[0 0 0])
                            plot(app.sensitivitiesAxes, [start(1,i) finish(1,i)],[start(2,i) finish(2,i)],'x-','color',[0 0 1])
                            plot(app.sensitivitiesAxes, abs(eig2(i)),wrapTo2Pi(angle(eig2(i)))*180/pi,'*','color',[1 0 0])
                        %end
                    end
                    xlabel(app.sensitivitiesAxes, 'Magnitude (rad/s)')
                    ylabel(app.sensitivitiesAxes, 'Phase (degrees)')
                    yline(app.sensitivitiesAxes, 90)
                    title(app.sensitivitiesAxes, 'Evolution of A eigenvalues')
                    legend(app.sensitivitiesAxes, 'Initial','Sensitivity','Final','Location','best')
                    axis(app.sensitivitiesAxes, [-Inf Inf -Inf 180])
                case 'Real & imag'
                    for i = 1:length(V1)
                        %if (abs(real(sensitivityeigs(i)))>0.1*abs(real(eig1(i)))) || (abs(sensitivityeigs(i)) > relsensitivity) ||  real(eig1(i)) > 0 
                            start(:,i) = [real(eig1(i)) imag(eig1(i))];
                            difference(:,i) = [real(sensitivityeigs(i)) imag(sensitivityeigs(i))];
                            plot(app.sensitivitiesAxes, real(eig1(i)),imag(eig1(i)),'o','color',[0 0 0])
                            quiver(app.sensitivitiesAxes, start(1,i),start(2,i),difference(1,i),difference(2,i),0,'color',[0 0 1])
                            plot(app.sensitivitiesAxes, real(eig2(i)),imag(eig2(i)),'*','color',[1 0 0])
                        %end
                    end
                    xlabel(app.sensitivitiesAxes, 'Real part (rad/s)')
                    ylabel(app.sensitivitiesAxes, 'Imaginary part (rad/s)')
                    title(app.sensitivitiesAxes, 'Evolution of A eigenvalues')
                    legend(app.sensitivitiesAxes, 'Initial','Sensitivity','Final','Location','best')
            end
            app.actual_axes_name = 'app.sensitivitiesAxes';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile({'*.mat','SMA data files'},'Select Simulink modal analysis tool data','SMA_data');
            drawnow;
            figure(app.SIMULINKmodalanalysisUIFigure)
            if path == 0
                return
            end
            load(fullfile(path, file), "prop_value")
            props = properties(app);
            try
                for iprop = 1:length(props)
                    actual_prop = props{iprop};
                    switch erase(class(app.(actual_prop)), "matlab.ui.control.")
                        case {"TextArea", "CheckBox", "EditField"}
                            app.(actual_prop).Value = prop_value.(actual_prop);
                        case "Spinner"
                            app.(actual_prop).Value = prop_value.(actual_prop).Value;
                            app.(actual_prop).Limits = prop_value.(actual_prop).Limits;
                        case "Table"
                            app.(actual_prop).Data = prop_value.(actual_prop);
                        case "Label"
                            app.(actual_prop).Text = prop_value.(actual_prop);
                        case "DropDown"
                            app.(actual_prop).Items =  prop_value.(actual_prop).Items;
                            app.(actual_prop).Value = prop_value.(actual_prop).Value;
                    end
                end
                app.linsys = prop_value.linsys;
                app.subsystem_name = prop_value.subsystem_name;
                try
                    SubsystemnamesTextAreaValueChanged(app);
                catch
                end
            catch
                uialert(app.SIMULINKmodalanalysisUIFigure, 'Invalid file for simulink modal analysis tool', 'Check file and app version')
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            props = properties(app);
            for iprop = 1:length(props)
                actual_prop = props{iprop};
                switch erase(class(app.(actual_prop)), "matlab.ui.control.")
                    case {"TextArea", "CheckBox", "EditField"}
                        prop_value.(actual_prop) = app.(actual_prop).Value;
                    case "Spinner"
                        prop_value.(actual_prop).Value = app.(actual_prop).Value;
                        prop_value.(actual_prop).Limits = app.(actual_prop).Limits;
                    case "Table"
                        prop_value.(actual_prop) = app.(actual_prop).Data;
                    case "Label"
                        prop_value.(actual_prop) = app.(actual_prop).Text;
                    case "DropDown"
                        prop_value.(actual_prop).Items = app.(actual_prop).Items;
                        prop_value.(actual_prop).Value = app.(actual_prop).Value;
                end
            end
            prop_value.linsys = app.linsys;
            prop_value.subsystem_name = app.subsystem_name;
            [file,path] = uiputfile({'*.mat','SMA data files'},'Select Simulink modal analysis tool data','SMA_data');
            drawnow;
            figure(app.SIMULINKmodalanalysisUIFigure)
            if path == 0
                return
            end
            save(fullfile(path, file), "prop_value")
        end

        % Button pushed function: TofigureButton
        function TofigureButtonPushed(app, event)
            f1 = figure('Resize','off','Position', eval([app.actual_axes_name '.Parent.Position']));
            u1 = uipanel(f1,'Position',[0 0 1 1]);
            try
                copyobj(findobj(allchild(eval([app.actual_axes_name '.Parent'])), 'type','Axes', '-or', 'type', 'Colorbar', ...
                    '-or', 'type', 'legend'), u1);
            catch ME
                if strcmp(ME.identifier,'MATLAB:Axes:UnsupportedCopy')
                    uialert(app.SIMULINKmodalanalysisUIFigure,'At the moment, this app does not support copying two-yaxis figure','Function not supported')
                    close(f1)
                    return
                end
            end
            dtip_old_obj = findobj(allchild(eval([app.actual_axes_name '.Parent'])),'-property','DataTipTemplate');
            dtip_new_obj = findobj(allchild(u1),'-property','DataTipTemplate');
            if length(dtip_old_obj) == length(dtip_new_obj) && ~isempty(dtip_old_obj)
                for i = 1:length(dtip_old_obj)
                    for ii = 1: length(dtip_old_obj(i).DataTipTemplate.DataTipRows) % For 2 first is different
                        if ii == 1 || ii == 2 % 2 first datatips are axis coordinates (exist also for new object)
                            dtip_new_obj(i).DataTipTemplate.DataTipRows(ii).Label = dtip_old_obj(i).DataTipTemplate.DataTipRows(ii).Label;
                        else
                            dtip_new_obj(i).DataTipTemplate.DataTipRows(ii) = dataTipTextRow(dtip_old_obj(i).DataTipTemplate.DataTipRows(ii).Label, ...
                                dtip_old_obj(i).DataTipTemplate.DataTipRows(ii).Value);
                        end
                    end
                end
            end
        end

        % Value changed function: SubsystemDropDown
        function SubsystemDropDownValueChanged(app, event)
            subsystem_index = find(string(app.SubsystemDropDown.Items) == app.SubsystemDropDown.Value, 1, 'first');
            yticklabels(app.PartiAxes, [])
            xticklabels(app.PartiAxes, [])
            xticks(app.PartiAxes, [])
            yticks(app.PartiAxes, [])
            unique_subsystem_name = sort(unique(app.subsystem_name));
            if subsystem_index == length(unique_subsystem_name)+1
                c = categorical(app.subsystem_name);
                c = cumsum(countcats(c)); c = c + 1;
                c1 = [1;c];
                [~, ind] = sort(app.linsys.StateName);
                partiplot = app.modparticipationori(ind,:); partiplot(end+1,end+1)=0;
                partiplot(partiplot<0.01) = 0;
                s = pcolor(app.PartiAxes, partiplot);
                s.EdgeColor = [1 1 1];
                title(app.PartiAxes, 'Participations of model')
                xlabel(app.PartiAxes, 'Eigenvalue (smallest to bigggest time-constant)')
                yticks(app.PartiAxes, c1) 
                yticklabels(app.PartiAxes, unique_subsystem_name)
                xticks(app.PartiAxes, 0.5+round(linspace(1, length(app.modparticipationori), ...
                    min(length(app.modparticipationori), 20))))
                set(app.PartiAxes,'xminorgrid','on','yminorgrid','off')
                app.PartiAxes.XAxis.MinorTickValues = 0.5:length(app.modparticipationori);
            else
                subsystem_states = app.linsys.StateName;
                subs_name = unique_subsystem_name(subsystem_index);
                if ismember(subs_name, app.SubsystemnamesTextArea.Value)
                    index = contains(subsystem_states, subs_name);
                else
                    index = contains(subsystem_states, strcat(subs_name, '/'));
                end
                partiplot = app.modparticipationori(index,:);
                partiplot(partiplot<0.01) = 0; partiplot(end+1,:) = 0;
                s = pcolor(app.PartiAxes, partiplot);
                s.EdgeColor = [1 1 1];
                title(app.PartiAxes, 'Participations of '+unique_subsystem_name(subsystem_index))
                xlabel(app.PartiAxes, 'Eigenvalue (smallest to bigggest time-constant)')
                yticks(app.PartiAxes, 1.5:length(index(index))+0.5)
                yticklabels(app.PartiAxes, subsystem_states(index))
                xticks(app.PartiAxes, 0.5+round(linspace(1, length(app.modparticipationori), ...
                    min(length(app.modparticipationori), 20))))
                set(app.PartiAxes,'xminorgrid','on','yminorgrid','off')
                app.PartiAxes.XAxis.MinorTickValues = 0.5:length(app.modparticipationori);
            end
        end

        % Callback function
        function CalculateStatereljumpButtonPushed(app, event)
            
            N_rel = length(app.linsys(:,:,1).a);
            if size(app.linsys,3) > 1 % Batch linearization
                param = find(app.ParamDropDown.Value == string(app.ParamDropDown.Items));
                app.ordered_states_batch = strings(N_rel, app.StepsSpinner.Value);
                app.R_state_batch = zeros(N_rel, app.StepsSpinner.Value);
                iteration_vector = (param-1)*app.StepsSpinner.Value+1:param*app.StepsSpinner.Value;
            else
                iteration_vector = 1;
                param = 1;
                app.ordered_states_batch = strings(N_rel, 1);
                app.R_state_batch = zeros(N_rel, 1);
            end
            for sys = iteration_vector
                wb = uiprogressdlg(app.SIMULINKmodalanalysisUIFigure, "Value",(sys-(param-1)*app.StepsSpinner.Value)/app.StepsSpinner.Value,...
                    "ShowPercentage","on","Message","Calculating state relevance");
                [V1,D1] = eig(app.linsys(:,:,sys).a); % eigenvalues of the system involved in this iteration
                [~,ind] = sort(diag(D1),'ComparisonMethod','real');
                W1 = inv(V1);
                V1 = V1(:,ind);
                W1 = W1(ind,:);
                Participation = zeros(length(V1));
                modparticipation = Participation;
                modpartiori = Participation;
                for i = 1:length(V1)
                    for n = 1:length(V1)
                        Participation(n,i)=W1(i,n)*V1(n,i);
                        modparticipation(n,i) = abs(Participation(n,i));
                    end
                    modpartiori(:,i)=modparticipation(:,i)/sum(modparticipation(:,i));
                end
                % Calculate participation factors in the BALANCED system
                [balsys, g, ~, ~] = balreal(app.linsys(:,:,sys));
                gnorm = g/max(g);
                [V1,D1] = eig(balsys.a);
                [~,ind] = sort(diag(D1),'ComparisonMethod','real');
                W1 = inv(V1);
                V1 = V1(:,ind);
                W1 = W1(ind,:);
                Participation = zeros(length(V1));
                modparticipation = Participation;
                modparticipationbal = Participation;
                for i = 1:length(V1)
                    for n = 1:length(V1)
                        Participation(n,i)=W1(i,n)*V1(n,i);
                        modparticipation(n,i) = abs(Participation(n,i));
                    end
                    modparticipationbal(:,i)=modparticipation(:,i)/sum(modparticipation(:,i));
                end
                R = gnorm'*modparticipationbal;
                R = R/max(R);
                R_state = modpartiori*(R'); % Calculate state relevance
                R_state = R_state/sum(R_state); % Normalize state relevance
                [R_state,ind] = sort(R_state,'descend');
                app.R_state_batch(:,sys-(param-1)*app.StepsSpinner.Value) = R_state(1:N_rel);
                app.ordered_states_batch(:,sys-(param-1)*app.StepsSpinner.Value) = app.linsys(:,:,1).StateName(ind);
            end
            close(wb)
        end

        % Value changed function: ParamDropDown, StatetofindEditField
        function StatetofindEditFieldValueChanged(app, event)
            if isempty(app.linsys)
                uialert(app.SIMULINKmodalanalysisUIFigure,'Import data first','System not detected')
                return
            end
            if size(app.linsys,3) == 1 % Single linearization
                app.EigenvaluerelevanceTab.Parent.SelectedTab = app.EigenvaluerelevanceTab;
                uialert(app.SIMULINKmodalanalysisUIFigure,'Relevance jump analysis is not valid for single linearization','Calculate batch linearization')
                return
            end
            param = find(app.ParamDropDown.Value == string(app.ParamDropDown.Items));
            thresold = 0.7; jump = ones(1,length(app.R_state_batch))*length(app.linsys.A); % If there is no jump big enough, all states are relevant;
            if size(app.linsys,3) > 1 % Batch linearization
                iteration_vector = 1:app.StepsSpinner.Value;
            else
                iteration_vector = 1;
            end
            while 1
                for ii = iteration_vector
                    if any(-diff(log10(app.R_state_batch(:,ii)))>thresold & (app.R_state_batch(2:end,ii) < 1e-2) & (app.R_state_batch(1:end-1,ii) > 1e-3))
                        jump(ii) = find(-diff(log10(app.R_state_batch(:,ii)))>thresold & (app.R_state_batch(2:end,ii) < 1e-2) & ...
                            (app.R_state_batch(1:end-1,ii) > 1e-3),1);
                    end
                    if isnan(app.R_state_batch(:,ii))
                        jump(ii) = 0;
                    end
                end
                if max(jump)<length(app.linsys.A) || thresold < 0
                    break;
                else
                    thresold = thresold-0.05;
                end
            end
            state_row = zeros(1,size(app.ordered_states_batch,2));
            R_state_iter = zeros(1,size(app.ordered_states_batch,2));
            for i = 1:size(app.ordered_states_batch,2)
                if ~isempty(find(contains(app.ordered_states_batch(:,i), app.StatetofindEditField.Value),1))
                    state_row(i) = find(contains(app.ordered_states_batch(:,i), app.StatetofindEditField.Value),1);
                    R_state_iter(i) = app.R_state_batch(state_row(i),i);
                else
                    state_row = [];
                    break
                end
            end
            if app.Batchtable.Data{param,4}
                parameter_values_noNaN = logspace(log10(app.Batchtable.Data{param,2}), log10(app.Batchtable.Data{param,3}), app.StepsSpinner.Value);
            else
                parameter_values_noNaN = linspace(app.Batchtable.Data{param,2}, app.Batchtable.Data{param,3}, app.StepsSpinner.Value);
            end
            if ~isempty(state_row)
                cla(app.RelJumpAxes, "reset")
                parameter_values_noNaN = parameter_values_noNaN(all(~isnan(app.R_state_batch),1));
                parameter_name = app.Batchtable.Data{param,1};
                xline(app.RelJumpAxes, evalin('base',parameter_name),'linewidth',0.1,'HandleVisibility','off')
                yyaxis(app.RelJumpAxes, "left")
                hold(app.RelJumpAxes, "on");
                plot(app.RelJumpAxes, parameter_values_noNaN,R_state_iter(all(~isnan(app.R_state_batch),1)),'b','linewidth',1,'DisplayName', ...
                    ['First ' app.StatetofindEditField.Value ' state relevance']);
                yyaxis(app.RelJumpAxes, "right")
                plot(app.RelJumpAxes, parameter_values_noNaN,jump(all(~isnan(app.R_state_batch),1)), 'red--','linewidth',2,'DisplayName','Last rel. state pos.');
                plot(app.RelJumpAxes, parameter_values_noNaN,state_row(all(~isnan(app.R_state_batch),1)), 'black-o','linewidth',2, ...
                    'DisplayName',['First ' app.StatetofindEditField.Value 'state pos.']);
                set(app.RelJumpAxes, 'ydir', 'reverse')
                xlabel(app.RelJumpAxes, app.Batchtable.Data{param,1})
                if any(~isnan(app.R_state_batch))
                    axis(app.RelJumpAxes, [-inf inf min([state_row(all(~isnan(app.R_state_batch),1)) jump(all(~isnan(app.R_state_batch),1))])-1 ...
                    max([state_row(all(~isnan(app.R_state_batch),1)) jump(all(~isnan(app.R_state_batch),1))])+1])
                end
                yyaxis(app.RelJumpAxes, "left")
                ylabel(app.RelJumpAxes, "State relevance")
                yyaxis(app.RelJumpAxes, "right")
                ylabel(app.RelJumpAxes, "State position by relevance")
                legend(app.RelJumpAxes, 'Location', 'northoutside','Orientation','horizontal');
            else
                uialert(app.SIMULINKmodalanalysisUIFigure,['State containing ' app.StatetofindEditField.Value ' not found'],'Check search string')
            end
            app.actual_axes_name = 'app.RelJumpAxes';
        end

        % Close request function: SIMULINKmodalanalysisUIFigure
        function SIMULINKmodalanalysisUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.SIMULINKmodalanalysisUIFigure, ...
                "Close app?","Unsaved data will be lost", ...
                "Icon","warning");
            if strcmp(selection, 'OK')
                delete(app)
            end
        end

        % Button pushed function: SavelinsystoworkspaceButton
        function SavelinsystoworkspaceButtonPushed(app, event)
            assignin("base", "linsys_sma", app.linsys)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SIMULINKmodalanalysisUIFigure = uifigure('Visible', 'off');
            app.SIMULINKmodalanalysisUIFigure.Name = 'SIMULINK modal analysis';
            app.SIMULINKmodalanalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @SIMULINKmodalanalysisUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.SIMULINKmodalanalysisUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.BrowseButton = uibutton(app.HomeTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SimulinkfileLabel = uilabel(app.HomeTab);
            app.SimulinkfileLabel.Text = 'Simulink file';
            app.ParamstochangeSpinnerLabel = uilabel(app.HomeTab);
            app.ParamstochangeSpinnerLabel.Text = 'Params. to change';
            app.ParamstochangeSpinner = uispinner(app.HomeTab);
            app.ParamstochangeSpinner.Limits = [1 100];
            app.ParamstochangeSpinner.ValueChangedFcn = createCallbackFcn(app, @ParamstochangeSpinnerValueChanged, true);
            app.ParamstochangeSpinner.Value = 2;
            app.Batchtable = uitable(app.HomeTab);
            app.Batchtable.ColumnName = {'Parameter'; 'Initial'; 'Final'; 'Log'};
            app.Batchtable.RowName = {};
            app.Batchtable.ColumnEditable = true;
            app.Batchtable.CellEditCallback = createCallbackFcn(app, @BatchtableCellEdit, true);
            app.Batchtable.DisplayDataChangedFcn = createCallbackFcn(app, @BatchtableCellEdit, true);
            app.StepsSpinnerLabel = uilabel(app.HomeTab);
            app.StepsSpinnerLabel.Text = 'Steps';
            app.StepsSpinner = uispinner(app.HomeTab);
            app.StepsSpinner.Limits = [1 1000];
            app.StepsSpinner.ValueChangedFcn = createCallbackFcn(app, @BatchtableCellEdit, true);
            app.StepsSpinner.Value = 2;
            app.BatchlinearizationButton = uibutton(app.HomeTab, 'push');
            app.BatchlinearizationButton.ButtonPushedFcn = createCallbackFcn(app, @BatchlinearizationButtonPushed, true);
            app.BatchlinearizationButton.Text = 'Batch linearization';
            app.SinglelinearizationButton = uibutton(app.HomeTab, 'push');
            app.SinglelinearizationButton.ButtonPushedFcn = createCallbackFcn(app, @SinglelinearizationButtonPushed, true);
            app.SinglelinearizationButton.Text = 'Single linearization';
            app.SubsystemnamesTextArea = uitextarea(app.HomeTab);
            app.SubsystemnamesTextArea.ValueChangedFcn = createCallbackFcn(app, @SubsystemnamesTextAreaValueChanged, true);
            app.SubsystemnamesTextAreaLabel = uilabel(app.HomeTab);
            app.SubsystemnamesTextAreaLabel.Text = 'Subsystem names';
            app.SimulinkblocksCheckBox = uicheckbox(app.HomeTab);
            app.SimulinkblocksCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubsystemnamesTextAreaValueChanged, true);
            app.SimulinkblocksCheckBox.Text = 'Simulink blocks';
            app.SimulinkblocksCheckBox.Value = true;
            app.statenamestoreplace = uitextarea(app.HomeTab);
            app.statenamestoreplace.Value = {'Grid/Dynamic/'};
            app.newstatenames = uitextarea(app.HomeTab);
            app.SubsystemnamesTextAreaLabel_2 = uilabel(app.HomeTab);
            app.SubsystemnamesTextAreaLabel_2.Text = 'State names to replace';
            app.SubsystemnamesTextAreaLabel_3 = uilabel(app.HomeTab);
            app.SubsystemnamesTextAreaLabel_3.Text = 'Old';
            app.SubsystemnamesTextAreaLabel_4 = uilabel(app.HomeTab);
            app.SubsystemnamesTextAreaLabel_4.Text = 'New';
            app.ReplacenamesButton = uibutton(app.HomeTab, 'push');
            app.ReplacenamesButton.ButtonPushedFcn = createCallbackFcn(app, @ReplacenamesButtonPushed, true);
            app.ReplacenamesButton.Text = 'Replace names';
            app.LoadButton = uibutton(app.HomeTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.HomeTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileLabel = uilabel(app.HomeTab);
            app.FileLabel.Text = 'File';
            app.HTML = uihtml(app.HomeTab);
            app.HTML.HTMLSource = '<img src=data:image/png;base64, width="450">';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
            app.Label = uilabel(app.HomeTab);
            app.Label.Text = '';
            app.ioEditField = uieditfield(app.HomeTab, 'text');
            app.ioEditField.Value = 'io';
            app.opEditField = uieditfield(app.HomeTab, 'text');
            app.opEditField.Value = 'op';
            app.Hyperlink = uihyperlink(app.HomeTab);
            app.Hyperlink.URL = 'https://www.iit.comillas.edu/index.php.en';
            app.Hyperlink.Text = 'Institute for Research in Technology';
            app.Hyperlink2 = uihyperlink(app.HomeTab);
            app.Hyperlink2.URL = 'https://www.comillas.edu/en/';
            app.Hyperlink2.Text = 'Comillas Pontifical University';
            app.Hyperlink3 = uihyperlink(app.HomeTab);
            app.Hyperlink3.URL = 'https://www.researchgate.net/profile/Andres_Tomas_Martin';
            app.Hyperlink3.Text = 'Andrés Tomás-Martín';
            app.ioCheckBox = uicheckbox(app.HomeTab);
            app.ioCheckBox.Text = 'io';
            app.opCheckBox = uicheckbox(app.HomeTab);
            app.opCheckBox.Text = 'op';
            app.LoaddatafromMATLABworkspaceLabel = uilabel(app.HomeTab);
            app.LoaddatafromMATLABworkspaceLabel.Text = 'Load data from MATLAB workspace';
            app.LoadlinsysButton = uibutton(app.HomeTab, 'push');
            app.LoadlinsysButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlinsysButtonPushed, true);
            app.LoadlinsysButton.Text = 'Load linsys';
            app.linsysnameEditFieldLabel = uilabel(app.HomeTab);
            app.linsysnameEditFieldLabel.Text = 'linsys name';
            app.linsysnameEditField = uieditfield(app.HomeTab, 'text');
            app.linsysnameEditField.Value = 'linsys';
            app.modelnameEditFieldLabel = uilabel(app.HomeTab);
            app.modelnameEditFieldLabel.Text = 'model name';
            app.modelnameEditField = uieditfield(app.HomeTab, 'text');
            app.modelnameEditField.Value = 'model';
            app.VaryonebyoneCheckBox = uicheckbox(app.HomeTab);
            app.VaryonebyoneCheckBox.Text = 'Vary one-by-one';
            app.VaryonebyoneCheckBox.Value = true;
            app.SavelinsystoworkspaceButton = uibutton(app.HomeTab, 'push');
            app.SavelinsystoworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SavelinsystoworkspaceButtonPushed, true);
            app.SavelinsystoworkspaceButton.Text = 'Save linsys to workspace';
            app.EigenvaluesTab = uitab(app.TabGroup);
            app.EigenvaluesTab.Title = 'Eigenvalues';
            app.EigenvaluesTab.ButtonDownFcn = createCallbackFcn(app, @EigenvaluesTabButtonDown, true);
            app.TabGroup3 = uitabgroup(app.EigenvaluesTab);
            app.SingleTab = uitab(app.TabGroup3);
            app.SingleTab.Title = 'Single';
            app.SingleTab.ButtonDownFcn = createCallbackFcn(app, @SingleTabButtonDown, true);
            app.ParamDropDown_2Label = uilabel(app.SingleTab);
            app.ParamDropDown_2Label.Text = 'Param';
            app.ParamDropDown_singleeigen = uidropdown(app.SingleTab);
            app.ParamDropDown_singleeigen.ValueChangedFcn = createCallbackFcn(app, @SingleTabButtonDown, true);
            app.Panel_3 = uipanel(app.SingleTab);
            app.singleeigenAxes = uiaxes(app.Panel_3);
            title(app.singleeigenAxes, 'Title')
            xlabel(app.singleeigenAxes, 'X')
            ylabel(app.singleeigenAxes, 'Y')
            zlabel(app.singleeigenAxes, 'Z')
            app.singleDropDown = uidropdown(app.SingleTab);
            app.singleDropDown.Items = {'Mag & phase', 'Mag & damp', 'Real & imag'};
            app.singleDropDown.ValueChangedFcn = createCallbackFcn(app, @SingleTabButtonDown, true);
            app.singleDropDown.Value = 'Mag & phase';
            app.UITab = uitab(app.TabGroup3);
            app.UITab.Title = 'UI';
            app.UITab.ButtonDownFcn = createCallbackFcn(app, @UITabButtonDown, true);
            app.ParamDropDown_2Label_2 = uilabel(app.UITab);
            app.ParamDropDown_2Label_2.Text = 'Param';
            app.ParamDropDown_UIeigen = uidropdown(app.UITab);
            app.ParamDropDown_UIeigen.ValueChangedFcn = createCallbackFcn(app, @UITabButtonDown, true);
            app.ParamValueLabel = uilabel(app.UITab);
            app.ParamValueLabel.Text = 'Value = ';
            app.Panel_2 = uipanel(app.UITab);
            app.UIeigenAxes = uiaxes(app.Panel_2);
            title(app.UIeigenAxes, 'Title')
            xlabel(app.UIeigenAxes, 'X')
            ylabel(app.UIeigenAxes, 'Y')
            zlabel(app.UIeigenAxes, 'Z')
            app.UIDropDown = uidropdown(app.UITab);
            app.UIDropDown.Items = {'Mag & phase', 'Mag & damp', 'Real & imag'};
            app.UIDropDown.ValueChangedFcn = createCallbackFcn(app, @UITabButtonDown, true);
            app.UIDropDown.Value = 'Mag & phase';
            app.ParticipationsCheckBox = uicheckbox(app.UITab);
            app.ParticipationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UITabButtonDown, true);
            app.ParticipationsCheckBox.Text = 'Participations';
            app.SpinnerUIeigen = uispinner(app.UITab);
            app.SpinnerUIeigen.Limits = [1 Inf];
            app.SpinnerUIeigen.ValueChangedFcn = createCallbackFcn(app, @UITabButtonDown, true);
            app.SpinnerUIeigen.Value = 1;
            app.IterationLabel = uilabel(app.UITab);
            app.IterationLabel.Text = 'Iteration';
            app.SensitivitiesTab = uitab(app.TabGroup3);
            app.SensitivitiesTab.Title = 'Sensitivities';
            app.SensitivitiesTab.ButtonDownFcn = createCallbackFcn(app, @SensitivitiesTabButtonDown, true);
            app.ParamDropDown_2Label_3 = uilabel(app.SensitivitiesTab);
            app.ParamDropDown_2Label_3.Text = 'Param';
            app.ParamDropDown_sensitivities = uidropdown(app.SensitivitiesTab);
            app.ParamDropDown_sensitivities.DropDownOpeningFcn = createCallbackFcn(app, @SensitivitiesTabButtonDown, true);
            app.ParamDropDown_2Label_4 = uilabel(app.SensitivitiesTab);
            app.ParamDropDown_2Label_4.Text = 'Value from';
            app.ParamDropDown_sensitivities_1 = uidropdown(app.SensitivitiesTab);
            app.ParamDropDown_sensitivities_1.ValueChangedFcn = createCallbackFcn(app, @ParamDropDown_sensitivities_1ValueChanged, true);
            app.ParamDropDown_2Label_5 = uilabel(app.SensitivitiesTab);
            app.ParamDropDown_2Label_5.Text = 'to';
            app.ParamDropDown_sensitivities_2 = uidropdown(app.SensitivitiesTab);
            app.ParamDropDown_sensitivities_2.ValueChangedFcn = createCallbackFcn(app, @ParamDropDown_sensitivities_1ValueChanged, true);
            app.Panel = uipanel(app.SensitivitiesTab);
            app.sensitivitiesAxes = uiaxes(app.Panel);
            title(app.sensitivitiesAxes, 'Title')
            xlabel(app.sensitivitiesAxes, 'X')
            ylabel(app.sensitivitiesAxes, 'Y')
            zlabel(app.sensitivitiesAxes, 'Z')
            app.sensitivitiesDropDown = uidropdown(app.SensitivitiesTab);
            app.sensitivitiesDropDown.Items = {'Mag & phase', 'Mag & damp', 'Real & imag'};
            app.sensitivitiesDropDown.ValueChangedFcn = createCallbackFcn(app, @SensitivitiesTabButtonDown, true);
            app.sensitivitiesDropDown.Value = 'Mag & phase';
            app.ParticipationsTab = uitab(app.TabGroup);
            app.ParticipationsTab.Title = 'Participations';
            app.ParticipationsTab.ButtonDownFcn = createCallbackFcn(app, @ParticipationsTabButtonDown, true);
            app.SystemDropDownLabel = uilabel(app.ParticipationsTab);
            app.SystemDropDownLabel.Text = 'System';
            app.SystemDropDown = uidropdown(app.ParticipationsTab);
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @ParticipationsTabButtonDown, true);
            app.Panel_7 = uipanel(app.ParticipationsTab);
            app.PartiAxes = uiaxes(app.Panel_7);
            title(app.PartiAxes, 'Title')
            app.SubsystemLabel = uilabel(app.ParticipationsTab);
            app.SubsystemLabel.Text = 'Subsystem';
            app.SubsystemDropDown = uidropdown(app.ParticipationsTab);
            app.SubsystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SubsystemDropDownValueChanged, true);
            app.RelevanceTab = uitab(app.TabGroup);
            app.RelevanceTab.Title = 'Relevance';
            app.RelevanceTab.ButtonDownFcn = createCallbackFcn(app, @RelevanceTabButtonDown, true);
            app.TabGroup2 = uitabgroup(app.RelevanceTab);
            app.EigenvaluerelevanceTab = uitab(app.TabGroup2);
            app.EigenvaluerelevanceTab.Title = 'Eigenvalue relevance';
            app.EigenvaluerelevanceTab.ButtonDownFcn = createCallbackFcn(app, @eigenDropDownValueChanged, true);
            app.eigenDropDown = uidropdown(app.EigenvaluerelevanceTab);
            app.eigenDropDown.Items = {'Mag & phase', 'Mag & damp', 'Real & imag', 'Stem'};
            app.eigenDropDown.ValueChangedFcn = createCallbackFcn(app, @eigenDropDownValueChanged, true);
            app.eigenDropDown.Value = 'Mag & phase';
            app.SystemDropDown_2Label = uilabel(app.EigenvaluerelevanceTab);
            app.SystemDropDown_2Label.Text = 'System';
            app.SystemDropDownRel = uidropdown(app.EigenvaluerelevanceTab);
            app.SystemDropDownRel.ValueChangedFcn = createCallbackFcn(app, @eigenDropDownValueChanged, true);
            app.Panel_4 = uipanel(app.EigenvaluerelevanceTab);
            app.PoleRelAxes = uiaxes(app.Panel_4);
            title(app.PoleRelAxes, 'Title')
            xlabel(app.PoleRelAxes, 'X')
            ylabel(app.PoleRelAxes, 'Y')
            zlabel(app.PoleRelAxes, 'Z')
            app.StaterelevanceTab = uitab(app.TabGroup2);
            app.StaterelevanceTab.Title = 'State relevance';
            app.StaterelevanceTab.ButtonDownFcn = createCallbackFcn(app, @SystemDropDownRelStatesValueChanged, true);
            app.SystemDropDown_2Label_2 = uilabel(app.StaterelevanceTab);
            app.SystemDropDown_2Label_2.Text = 'System';
            app.SystemDropDownRelStates = uidropdown(app.StaterelevanceTab);
            app.SystemDropDownRelStates.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownRelStatesValueChanged, true);
            app.TabGroup4 = uitabgroup(app.StaterelevanceTab);
            app.StaterelevancetableTab = uitab(app.TabGroup4);
            app.StaterelevancetableTab.Title = 'State relevance table';
            app.StateTable = uitable(app.StaterelevancetableTab);
            app.StateTable.ColumnName = {'Pos'; 'State name'; 'Relevance'};
            app.StateTable.RowName = {};
            app.StateTable.ColumnSortable = [true true];
            app.StaterelevanceplotTab = uitab(app.TabGroup4);
            app.StaterelevanceplotTab.Title = 'State relevance plot';
            app.Panel_5 = uipanel(app.StaterelevanceplotTab);
            app.StateRelAxes = uiaxes(app.Panel_5);
            title(app.StateRelAxes, 'Title')
            xlabel(app.StateRelAxes, 'X')
            ylabel(app.StateRelAxes, 'Y')
            zlabel(app.StateRelAxes, 'Z')
            app.XlabelforploteverynumberofstatesSpinnerLabel = uilabel(app.StaterelevanceTab);
            app.XlabelforploteverynumberofstatesSpinnerLabel.Text = 'Xlabel for plot every number of states ';
            app.XlabelforploteverynumberofstatesSpinner = uispinner(app.StaterelevanceTab);
            app.XlabelforploteverynumberofstatesSpinner.Limits = [1 Inf];
            app.XlabelforploteverynumberofstatesSpinner.ValueChangedFcn = createCallbackFcn(app, @RefreshxaxislabelsforplotButtonPushed, true);
            app.XlabelforploteverynumberofstatesSpinner.Value = 1;
            app.StaterelevancejumpTab = uitab(app.TabGroup2);
            app.StaterelevancejumpTab.Title = 'State relevance jump';
            app.StaterelevancejumpTab.ButtonDownFcn = createCallbackFcn(app, @RelJumpAxesButtonDown, true);
            app.SystemDropDown_2Label_3 = uilabel(app.StaterelevancejumpTab);
            app.SystemDropDown_2Label_3.Text = 'Param';
            app.ParamDropDown = uidropdown(app.StaterelevancejumpTab);
            app.ParamDropDown.ValueChangedFcn = createCallbackFcn(app, @StatetofindEditFieldValueChanged, true);
            app.StatetofindEditFieldLabel = uilabel(app.StaterelevancejumpTab);
            app.StatetofindEditFieldLabel.Text = 'State to find';
            app.StatetofindEditField = uieditfield(app.StaterelevancejumpTab, 'text');
            app.StatetofindEditField.ValueChangedFcn = createCallbackFcn(app, @StatetofindEditFieldValueChanged, true);
            app.Panel_6 = uipanel(app.StaterelevancejumpTab);
            app.RelJumpAxes = uiaxes(app.Panel_6);
            title(app.RelJumpAxes, 'z')
            xlabel(app.RelJumpAxes, 'X')
            ylabel(app.RelJumpAxes, 'Y')
            zlabel(app.RelJumpAxes, 'Z')
            app.TofigureButton = uibutton(app.SIMULINKmodalanalysisUIFigure, 'push');
            app.TofigureButton.ButtonPushedFcn = createCallbackFcn(app, @TofigureButtonPushed, true);
            app.TofigureButton.Text = 'To figure';
            % Show the figure after all components are created
            app.SIMULINKmodalanalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulink_modal_analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIMULINKmodalanalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIMULINKmodalanalysisUIFigure)
        end
    end
end